classdef naveen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IntensityThresholdSliderLabel  matlab.ui.control.Label
        IntensityThresholdSlider       matlab.ui.control.Slider
        IntensitythresholdEditFieldLabel  matlab.ui.control.Label
        IntensitythresholdEditField    matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        DatasetDropDownLabel           matlab.ui.control.Label
        DatasetDropDown                matlab.ui.control.DropDown
        MinblobsizeEditFieldLabel      matlab.ui.control.Label
        MinblobsizeEditField           matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        im % Description
        perimImage
        %threshold
    end
    
    properties (Access = private)
        dataTable % Description
        saveFilename % Description
    end
    
    methods (Access = private)
        
        function perim = showPerim(app)
            thresh = app.IntensityThresholdSlider.Value;
            %bw = bwperim(app.im > thresh);
            bw = app.im > thresh;
            bw = imclose(bw,strel('disk',40));
            bw = bwpropfilt(bw,'Area',[str2double(app.MinblobsizeEditField.Value), 1e9]);
            im = imoverlay(imadjust(app.im),imdilate(bw,strel('disk',5)),'red');
            imshow(im,'parent',app.UIAxes);
            CC = bwconncomp(bw);
            
            currDataset = find(app.dataTable.Dataset == string(app.DatasetDropDown.Value));
            app.dataTable.Area(currDataset) = sum(bw(:));
            app.dataTable.NumBlobs(currDataset) = CC.NumObjects;
            app.dataTable.IntensityThresh(currDataset) = thresh;
            app.dataTable.BlobThresh(currDataset) = str2double(app.MinblobsizeEditField.Value);
            
            app.UITable.Data = app.dataTable;
            
            writetable(app.dataTable,app.saveFilename);
            
            % Set up table
            
        end
        
        function results = loadNewFile(app)
            app.im = imread(app.DatasetDropDown.Value);
            app.IntensityThresholdSlider.Limits = double([min(app.im(:)),max(app.im(:))]);
            app.IntensityThresholdSlider.Value = double(median(app.im(:)));
            app.showPerim();
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inFilename)
            app.saveFilename = inFilename;
            
            dd = dir('*.tif');
            filenames = string({dd.name});
            app.DatasetDropDown.Items = filenames;
            app.DatasetDropDown.Value = filenames(1);
            
            varTypes = ["string","double","double","double","double"];
            varNames = ["Dataset","Area","NumBlobs","IntensityThresh","BlobThresh"];
            
            app.dataTable = table('Size',[length(app.DatasetDropDown.Items),5],'VariableTypes',varTypes,'VariableNames',varNames);
            app.dataTable.Dataset = string(app.DatasetDropDown.Items)';
            app.UITable.Data = app.dataTable;
            
            app.loadNewFile();
        end

        % Value changed function: IntensityThresholdSlider
        function IntensityThresholdSliderValueChanged(app, event)
            app.IntensitythresholdEditField.Value = app.IntensityThresholdSlider.Value;
            app.showPerim();
        end

        % Value changed function: IntensitythresholdEditField
        function IntensitythresholdEditFieldValueChanged(app, event)
            app.IntensityThresholdSlider.Value = app.IntensitythresholdEditField.Value;
            app.showPerim();
        end

        % Value changed function: MinblobsizeEditField
        function MinblobsizeEditFieldValueChanged(app, event)
            value = app.MinblobsizeEditField.Value;
            app.showPerim();
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            app.loadNewFile();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IntensityThresholdSliderLabel = uilabel(app.UIFigure);
            app.IntensityThresholdSliderLabel.Text = 'IntensityThreshold';
            app.IntensityThresholdSlider = uislider(app.UIFigure);
            app.IntensityThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @IntensityThresholdSliderValueChanged, true);
            app.IntensitythresholdEditFieldLabel = uilabel(app.UIFigure);
            app.IntensitythresholdEditFieldLabel.Text = 'Intensity threshold';
            app.IntensitythresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensitythresholdEditField.ValueChangedFcn = createCallbackFcn(app, @IntensitythresholdEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Dataset'; 'Area'; 'NumBlobs'; 'IntensityThresh'; 'BlobThresh'};
            app.UITable.RowName = {};
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.MinblobsizeEditFieldLabel = uilabel(app.UIFigure);
            app.MinblobsizeEditFieldLabel.Text = 'Min blob size';
            app.MinblobsizeEditField = uieditfield(app.UIFigure, 'text');
            app.MinblobsizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinblobsizeEditFieldValueChanged, true);
            app.MinblobsizeEditField.Value = '500';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = naveen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end